classdef bootycheeks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RPMTextAreaLabel               matlab.ui.control.Label
        RPMTextArea                    matlab.ui.control.TextArea
        SpeedTextAreaLabel             matlab.ui.control.Label
        SpeedTextArea                  matlab.ui.control.TextArea
        DistanceTraveledTextAreaLabel  matlab.ui.control.Label
        DistanceTraveledTextArea       matlab.ui.control.TextArea
        CruiseControlRPMTextAreaLabel  matlab.ui.control.Label
        CruiseControlRPMTextArea       matlab.ui.control.TextArea
        TemperatureCLabel              matlab.ui.control.Label
        TemperatureCTextArea           matlab.ui.control.TextArea
        CoolantLevelTextAreaLabel      matlab.ui.control.Label
        CoolantLevelTextArea           matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        TempLED                        matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        CoolantLED                     matlab.ui.control.Lamp
        PartyTimeButton                matlab.ui.control.Button
        TimeRunningTextAreaLabel       matlab.ui.control.Label
        TimeRunningTextArea            matlab.ui.control.TextArea
        BatteryTextAreaLabel           matlab.ui.control.Label
        BatteryTextArea                matlab.ui.control.TextArea
        SpeedGauge                     matlab.ui.control.Gauge
        StartStopButton                matlab.ui.control.StateButton
        CoolantGauge                   matlab.ui.control.LinearGauge
        TempGauge                      matlab.ui.control.LinearGauge
        BatteryGauge                   matlab.ui.control.LinearGauge
        mphLabel                       matlab.ui.control.Label
        MilesLabel                     matlab.ui.control.Label
        SecondsLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
    end


    methods (Access = public)


    end


    methods (Access = private)

        % Button pushed function: PartyTimeButton
        function PartyTimeButtonPushed(app, event)
            web("https://www.youtube.com/watch?v=4pkhEyRoidk") 
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
                                         
            %scope=visa('agilent', 'USB0::0x0957::0x1799::MY58100812::0::INSTR');
            DMM=visa('agilent', 'USB0::0x2A8D::0xB318::MY58150015::0::INSTR');
            
            powsup=visa('agilent', 'USB0::0x2A8D::0x1202::MY58180406::0::INSTR');
            a=serial('COM73','BaudRate',9600);
            
              
            fopen(powsup);
            %fopen(scope);
            fopen(DMM);
            fopen(a);


            data=[];
            
            
            tic
            while app.StartStopButton.Value%start
            
            fprintf(powsup,'APPL CH2,12,DEF');    
            drawnow()
                       
            
            for i=1:3    
            data(:,i)=str2double(fscanf(a));
            end
            
            if length(data) == 3
            rpm=data(1);
            Temp=data(2);
            CoolantAna=data(3);
            end
            
            
            %battery lvl
%             V=str2double(query(DMM,'MEAS:VOLT?'));
%             Batterylvl=((V-5)/3.4);
%             datastruc.Batterylvl=num2str(Batterylvl);
            
            datastruc.RPM=num2str(rpm);
            %datastruc.RPM='350';
            
            %rpm=str2double(datastruc.RPM);%turning RPM into a numerical value
            
            %Speed calculation
            circum=2.625*pi; %diameter of wheel is 2 5/8 inches
            Speed=(rpm*circum*60)/63360; %(rpm*circumference*minutes in hour)/inches in a mile
            datastruc.Speed=num2str(Speed);
      
            
            %Distance Traveled
            hourelaps=toc/3600;
            DisTrav=Speed*hourelaps;
            datastruc.DisTrav=num2str(DisTrav);
            
            
            %Temperature handling
            %Temp=26;
            datastruc.Temp=num2str(Temp);
            if Temp >= 27
               app.TempLED.Enable='on';
            else
               app.TempLED.Enable='off'; 
            end


            %Coolant handling
            %CoolantAna=190;
            maxcoolant=1023;
            if CoolantAna <= 200
               app.CoolantLED.Enable='on';
            else
               app.CoolantLED.Enable='off'; 
            end
            Coolant=CoolantAna*100/maxcoolant;
            datastruc.Coolant=num2str(Coolant);
            
            %Cruise
            datastruc.Cruise='120';
            
            %Time elaspsed
            datastruc.TimeRun=num2str(toc);
            
            app.RPMTextArea.Value=datastruc.RPM;
            app.SpeedTextArea.Value=datastruc.Speed;
            app.SpeedGauge.Value=Speed;
            app.DistanceTraveledTextArea.Value=datastruc.DisTrav;
            app.TemperatureCTextArea.Value=datastruc.Temp;
            app.TempGauge.Value=Temp;
            app.CoolantLevelTextArea.Value=datastruc.Coolant;
            app.CoolantGauge.Value=Coolant;
            app.CruiseControlRPMTextArea.Value=datastruc.Cruise;
            app.TimeRunningTextArea.Value=datastruc.TimeRun;
%             app.BatteryGauge.Value=Batterylvl*100;
%             app.BatteryTextArea.Value=datastruc.Batterylvl;
            
            pause(0.5)
              
            
            end
            
                        fprintf(powsup,'APPL CH2,0,DEF');
                        
                        fclose(a);
                        fclose(powsup);
                        %fclose(scope);
                        fclose(DMM);
                        delete(a);
                        %delete(scope);
                        delete(powsup);
                        delete(DMM);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RPMTextAreaLabel = uilabel(app.UIFigure);
            app.RPMTextAreaLabel.Text = 'RPM';
            app.RPMTextArea = uitextarea(app.UIFigure);
            app.RPMTextArea.Editable = 'off';
            app.SpeedTextAreaLabel = uilabel(app.UIFigure);
            app.SpeedTextAreaLabel.Text = 'Speed';
            app.SpeedTextArea = uitextarea(app.UIFigure);
            app.SpeedTextArea.Editable = 'off';
            app.DistanceTraveledTextAreaLabel = uilabel(app.UIFigure);
            app.DistanceTraveledTextAreaLabel.Text = 'Distance Traveled';
            app.DistanceTraveledTextArea = uitextarea(app.UIFigure);
            app.DistanceTraveledTextArea.Editable = 'off';
            app.CruiseControlRPMTextAreaLabel = uilabel(app.UIFigure);
            app.CruiseControlRPMTextAreaLabel.Text = 'Cruise Control RPM';
            app.CruiseControlRPMTextArea = uitextarea(app.UIFigure);
            app.CruiseControlRPMTextArea.Editable = 'off';
            app.TemperatureCLabel = uilabel(app.UIFigure);
            app.TemperatureCLabel.Text = 'Temperature (°C)';
            app.TemperatureCTextArea = uitextarea(app.UIFigure);
            app.TemperatureCTextArea.Editable = 'off';
            app.CoolantLevelTextAreaLabel = uilabel(app.UIFigure);
            app.CoolantLevelTextAreaLabel.Text = 'Coolant Level';
            app.CoolantLevelTextArea = uitextarea(app.UIFigure);
            app.CoolantLevelTextArea.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.TempLED = uilamp(app.UIFigure);
            app.TempLED.Enable = 'off';
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Enable = 'off';
            app.Lamp_2Label.Text = '';
            app.CoolantLED = uilamp(app.UIFigure);
            app.CoolantLED.Enable = 'off';
            app.PartyTimeButton = uibutton(app.UIFigure, 'push');
            app.PartyTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PartyTimeButtonPushed, true);
            app.PartyTimeButton.Icon = '291107168009211.png';
            app.PartyTimeButton.Text = 'Party Time';
            app.TimeRunningTextAreaLabel = uilabel(app.UIFigure);
            app.TimeRunningTextAreaLabel.Text = 'Time Running';
            app.TimeRunningTextArea = uitextarea(app.UIFigure);
            app.TimeRunningTextArea.Editable = 'off';
            app.BatteryTextAreaLabel = uilabel(app.UIFigure);
            app.BatteryTextAreaLabel.Text = 'Battery %';
            app.BatteryTextArea = uitextarea(app.UIFigure);
            app.BatteryTextArea.Editable = 'off';
            app.SpeedGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedGauge.Limits = [0 3];
            app.SpeedGauge.MajorTicks = [0 0.3 0.6 0.9 1.2 1.5 1.8 2.1 2.4 2.7 3];
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.CoolantGauge = uigauge(app.UIFigure, 'linear');
            app.TempGauge = uigauge(app.UIFigure, 'linear');
            app.BatteryGauge = uigauge(app.UIFigure, 'linear');
            app.mphLabel = uilabel(app.UIFigure);
            app.mphLabel.Text = 'mph';
            app.MilesLabel = uilabel(app.UIFigure);
            app.MilesLabel.Text = '(Miles)';
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Text = '(Seconds)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'American', 'Correct'};
            app.Switch.Value = 'American';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bootycheeks

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end